classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlottingButton  matlab.ui.control.Button
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        arduinoObj % arduinoObj port
    end
    
    methods (Access = private)
        
        function readArduinoData(app, src, ~)
            
            data = readline(src);
            data = split(data,',');
            
            IR = data(1);
            RED = data(2);
            TEMP = data(3);
                        
            src.UserData.IR(end+1) = str2double(IR);
            src.UserData.RED(end+1) = str2double(RED);
            src.UserData.TEMP(end+1) = str2double(TEMP);
            
            plot(app.UIAxes,src.UserData.IR);
            plot(app.UIAxes_2,src.UserData.RED);
            plot(app.UIAxes2,src.UserData.TEMP);

         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlottingButton
        function PlottingButtonPushed(app, event)
            
            app.arduinoObj = serialport("COM5",115200);
            configureTerminator(app.arduinoObj,"CR/LF");
            flush(app.arduinoObj);
            app.arduinoObj.UserData = struct("Data",[],"IR",[],"RED",[],"TEMP",[]);
            configureCallback(app.arduinoObj,"terminator",@(src, event) readArduinoData(app,src,event));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IR Graph')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Ir')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'RED Graph')
            xlabel(app.UIAxes_2, 'Sample')
            ylabel(app.UIAxes_2, 'Red')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Temperature Graph')
            xlabel(app.UIAxes2, 'Sample')
            ylabel(app.UIAxes2, 'C*')
            zlabel(app.UIAxes2, 'Z')
            app.PlottingButton = uibutton(app.UIFigure, 'push');
            app.PlottingButton.ButtonPushedFcn = createCallbackFcn(app, @PlottingButtonPushed, true);
            app.PlottingButton.Text = 'Plotting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end